classdef RippleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PositionPlotFocusDropDown       matlab.ui.control.DropDown
        PositionPlotFocusDropDownLabel  matlab.ui.control.Label
        NumTetrodesEditField            matlab.ui.control.NumericEditField
        NumTetrodesEditFieldLabel       matlab.ui.control.Label
        StddevcutoffEditField           matlab.ui.control.NumericEditField
        StddevcutoffEditFieldLabel      matlab.ui.control.Label
        MindurationsamplesEditField     matlab.ui.control.NumericEditField
        MindurationsamplesEditFieldLabel  matlab.ui.control.Label
        SWRwindowsamplesEditField       matlab.ui.control.NumericEditField
        SWRwindowsamplesEditFieldLabel  matlab.ui.control.Label
        BandHzSlider                    matlab.ui.control.RangeSlider
        BandHzSliderLabel               matlab.ui.control.Label
        FindRipplesButton               matlab.ui.control.Button
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        LFPDataSelectionLabel           matlab.ui.control.Label
        TaskDropDown                    matlab.ui.control.DropDown
        TaskDropDownLabel               matlab.ui.control.Label
        SWRdetectionparamsLabel         matlab.ui.control.Label
        SamplingFreqHzEditField         matlab.ui.control.NumericEditField
        SamplingFreqHzEditFieldLabel    matlab.ui.control.Label
        LoadExperimentButton            matlab.ui.control.Button
        AnimalNameDropDown              matlab.ui.control.DropDown
        AnimalNameDropDownLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GotoLabel                       matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        SWRPropertiesLabel              matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %lfp_data  % Current lfp_data for plot
        %time_vec  % current time vector for plotted LFP data
        %pos_data % current position data matrix (x,y,t,v)
        %total_time
        yOffset = 500;  % How far apart each of the tetrodes will be
        totalNumTetrodes = 7;  % Number of tetrodes available based on current dataset
        experiment % Experiment object
        current_displayed_ripple_id
    end
    
    methods (Access = private)
        
        function updateSWREventPlot(app)
            currentRipple = app.experiment.merged_swr_events{app.current_displayed_ripple_id}(1);
            ripple_info = currentRipple.getProperties();  % props of the base ripple
            additionalInfo = sprintf('Showing SWR Event: %d\n', app.current_displayed_ripple_id);
            app.TextArea.Value = [additionalInfo, newline, ripple_info];
            app.experiment.displayMergedEvent(app.UIAxes3, app.current_displayed_ripple_id)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadExperimentButton
        function LoadDataSet(app, event)
            selectedAnimalName = app.AnimalNameDropDown.Value;
            selectedTaskName = app.TaskDropDown.Value;

            % initialize the lfp data. will be a 7x1 array
            lfp_data = get_lfp_data(selectedAnimalName, selectedTaskName, app.totalNumTetrodes);
            sampling_freq = app.SamplingFreqHzEditField.Value;

            % Load position data.
            pos_data = get_pos_data(selectedAnimalName, selectedTaskName);  % has .x, .y, .v, .t

            % Create the Experiment object
            app.experiment = LFP_Experiment(lfp_data, sampling_freq, pos_data, app.totalNumTetrodes);
            
            % Plot the LFP data for all 7 tetrodes by default
            cla(app.UIAxes, 'reset');
            %app.PlotrangestartsEditField.Value = 0;
            %app.PlotrangeendsEditField.Value = app.experiment.total_time;
            plotLFPDataOnAxes(app.UIAxes, app.experiment.lfp_data, app.experiment.time_vec, 0, app.experiment.total_time, app.yOffset, 1:app.totalNumTetrodes);
            
            % Plot position data
            if app.PositionPlotFocusDropDown.Value == "Velocity"
                % Link the x-axes of the two plots
                linkaxes([app.UIAxes, app.UIAxes2], 'x');
                plotVelocity(app.UIAxes2, app.experiment.pos_data);
            elseif app.PositionPlotFocusDropDown.Value == "Position"
                % Unlink the x-axes of the two plots
                linkaxes([app.UIAxes, app.UIAxes2], 'off');
                plotPosition(app.UIAxes2, app.experiment.pos_data)
            end

        end

        % Callback function
        function AnimalDataDrop(app, event)
            %value = app.AnimalNameDropDown.Value;
            
        end

        % Callback function
        function DataDropDownValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function PlotRangeStartChanged(app, event)
            %value = app.PlotrangestartsEditField.Value;
            %plotLFPDataOnAxes(app.UIAxes, app.experiment.lfp_data, app.experiment.time_vec, app.PlotrangestartsEditField.Value, app.PlotrangeendsEditField.Value, app.yOffset);
        end

        % Callback function: not associated with a component
        function PlotRangeEndChanged(app, event)
            %value = app.PlotrangeendsEditField.Value;
            %plotLFPDataOnAxes(app.UIAxes, app.experiment.lfp_data, app.experiment.time_vec, app.PlotrangestartsEditField.Value, app.PlotrangeendsEditField.Value, app.yOffset);
        end

        % Button down function: LeftPanel
        function ResetButtonPressed(app, event)
            % does nothing lmao
            %app.PlotrangestartsEditField.Value = 0;
            %app.PlotrangeendsEditField.Value = app.experiment.total_time;
        end

        % Button pushed function: FindRipplesButton
        function FindRippleButtonPushed(app, event)
            % Find Ripples
            app.experiment = app.experiment.detectRipples(app.StddevcutoffEditField.Value, app.BandHzSlider.Value, app.SWRwindowsamplesEditField.Value, app.MindurationsamplesEditField.Value);
            % Merge
            app.experiment = app.experiment.mergeSWR_Events();
            % Draw boxes on main plot
            app.experiment.drawMergedSWREventBoxes(app.UIAxes, app.yOffset)
            % Draw first ripple in ripple plot
            app.current_displayed_ripple_id = 1;  % init this
            updateSWREventPlot(app);
        end

        % Value changed function: BandHzSlider
        function MinSWRFreqBandChanged(app, event)
            %value = app.BandHzSlider.Value;
            
        end

        % Value changing function: BandHzSlider
        function MaxSWRFreqBandChanged(app, event)
            %changingValue = event.Value;
            
        end

        % Callback function
        function MouseTaskPlotButton(app, event)

        end

        % Callback function: not associated with a component
        function PositionPlotUpdatePressed(app, event)

        end

        % Value changed function: NumTetrodesEditField
        function numTetrodesChanged(app, event)
            app.totalNumTetrodes = app.NumTetrodesEditField.Value;
            LoadDataSet(app, event)  % update the plot by simulating button press
        end

        % Value changed function: PositionPlotFocusDropDown
        function PositionPlotFocusChanged(app, event)
            value = app.PositionPlotFocusDropDown.Value;
            % Plot position data
            if app.PositionPlotFocusDropDown.Value == "Velocity"
                % Link the x-axes of the two plots
                linkaxes([app.UIAxes, app.UIAxes2], 'x');
                plotVelocity(app.UIAxes2, app.experiment.pos_data);
            elseif app.PositionPlotFocusDropDown.Value == "Position"
                % Unlink the x-axes of the two plots
                linkaxes([app.UIAxes, app.UIAxes2], 'off');
                plotPosition(app.UIAxes2, app.experiment.pos_data)
            end
        end

        % Button pushed function: PrevButton
        function ripple_id_prev_pressed(app, event)
            app.current_displayed_ripple_id = max(1, app.current_displayed_ripple_id - 1);
            updateSWREventPlot(app);
        end

        % Button pushed function: NextButton
        function ripple_id_next_pressed(app, event)
            max_ripples = length(app.experiment.merged_swr_events);
            app.current_displayed_ripple_id = min(max_ripples, app.current_displayed_ripple_id + 1);
            updateSWREventPlot(app);
        end

        % Value changed function: EditField
        function ripple_id_set(app, event)
            value = app.EditField.Value;
            max_ripples = length(app.experiment.merged_swr_events);  % Adjust based on your actual array
            app.current_displayed_ripple_id = max(1, min(max_ripples, value));
            updateSWREventPlot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {642, 642};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @ResetButtonPressed, true);
            app.AnimalNameDropDownLabel = uilabel(app.LeftPanel);
            app.AnimalNameDropDownLabel.Text = 'Animal Name';
            app.AnimalNameDropDown = uidropdown(app.LeftPanel);
            app.AnimalNameDropDown.Items = {'HPa', 'HPb', 'HPc'};
            app.AnimalNameDropDown.Value = 'HPa';
            app.LoadExperimentButton = uibutton(app.LeftPanel, 'push');
            app.LoadExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSet, true);
            app.LoadExperimentButton.Text = 'Load Experiment';
            app.SamplingFreqHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingFreqHzEditFieldLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingFreqHzEditField.Limits = [0 100000];
            app.SamplingFreqHzEditField.RoundFractionalValues = 'on';
            app.SamplingFreqHzEditField.Value = 1500;
            app.SWRdetectionparamsLabel = uilabel(app.LeftPanel);
            app.SWRdetectionparamsLabel.Text = 'SWR detection params';
            app.TaskDropDownLabel = uilabel(app.LeftPanel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.LeftPanel);
            app.TaskDropDown.Items = {'    Day1_Task2: ''run - lin''', '    Day1_Task4: ''run - wtr1''', '    Day1_Task6: ''run - wtr1''', '    Day2_Task2: ''run - wtr1''', '    Day2_Task4: ''run - wtr1''', '    Day3_Task2: ''run - wtr1''', '    Day3_Task4: ''run - wtr1''', '    Day4_Task2: ''run - wtr1''', '    Day4_Task4: ''run - wtr1''', '    Day5_Task2: ''run - wtr1''', '    Day5_Task4: ''run - wtr1''', '    Day6_Task2: ''run - wtr1''', '    Day6_Task4: ''run - wtr2''', '    Day7_Task2: ''run - wtr1''', '    Day7_Task4: ''run - wtr2''', '    Day8_Task2: ''run - wtr1''', '    Day8_Task4: ''run - wtr2'''};
            app.TaskDropDown.Value = '    Day1_Task2: ''run - lin''';
            app.LFPDataSelectionLabel = uilabel(app.LeftPanel);
            app.LFPDataSelectionLabel.Text = 'LFP Data Selection';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'RP_Detect'};
            app.MethodDropDown.Value = 'RP_Detect';
            app.FindRipplesButton = uibutton(app.LeftPanel, 'push');
            app.FindRipplesButton.ButtonPushedFcn = createCallbackFcn(app, @FindRippleButtonPushed, true);
            app.FindRipplesButton.Text = 'Find Ripples';
            app.BandHzSliderLabel = uilabel(app.LeftPanel);
            app.BandHzSliderLabel.Text = 'Band (Hz)';
            app.BandHzSlider = uislider(app.LeftPanel, 'range');
            app.BandHzSlider.Limits = [100 300];
            app.BandHzSlider.ValueChangedFcn = createCallbackFcn(app, @MinSWRFreqBandChanged, true);
            app.BandHzSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSWRFreqBandChanged, true);
            app.BandHzSlider.Step = 5;
            app.BandHzSlider.Value = [150 250];
            app.SWRwindowsamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.SWRwindowsamplesEditFieldLabel.Text = 'SWR window (samples)';
            app.SWRwindowsamplesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SWRwindowsamplesEditField.Limits = [0 1000];
            app.SWRwindowsamplesEditField.Value = 225;
            app.MindurationsamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.MindurationsamplesEditFieldLabel.Text = 'Min duration (samples)';
            app.MindurationsamplesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MindurationsamplesEditField.Limits = [0 100];
            app.MindurationsamplesEditField.Value = 45;
            app.StddevcutoffEditFieldLabel = uilabel(app.LeftPanel);
            app.StddevcutoffEditFieldLabel.Text = 'Std. dev cutoff';
            app.StddevcutoffEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StddevcutoffEditField.Limits = [0 20];
            app.StddevcutoffEditField.RoundFractionalValues = 'on';
            app.StddevcutoffEditField.Value = 2;
            app.NumTetrodesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumTetrodesEditFieldLabel.Text = 'Num Tetrodes';
            app.NumTetrodesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumTetrodesEditField.Limits = [0 25];
            app.NumTetrodesEditField.ValueChangedFcn = createCallbackFcn(app, @numTetrodesChanged, true);
            app.NumTetrodesEditField.Value = 7;
            app.PositionPlotFocusDropDownLabel = uilabel(app.LeftPanel);
            app.PositionPlotFocusDropDownLabel.Text = 'Position Plot Focus';
            app.PositionPlotFocusDropDown = uidropdown(app.LeftPanel);
            app.PositionPlotFocusDropDown.Items = {'Position', 'Velocity'};
            app.PositionPlotFocusDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionPlotFocusChanged, true);
            app.PositionPlotFocusDropDown.Value = 'Velocity';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Animal Position')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'SWR Event')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PrevButton = uibutton(app.RightPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @ripple_id_prev_pressed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.RightPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @ripple_id_next_pressed, true);
            app.NextButton.Text = 'Next';
            app.TextArea = uitextarea(app.RightPanel);
            app.SWRPropertiesLabel = uilabel(app.RightPanel);
            app.SWRPropertiesLabel.Text = 'SWR Properties';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ripple_id_set, true);
            app.EditField.Value = 1;
            app.GotoLabel = uilabel(app.RightPanel);
            app.GotoLabel.Text = 'Go to:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RippleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end